classdef DM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Lamp_2                matlab.ui.control.Lamp
        StopButton            matlab.ui.control.Button
        BLabel                matlab.ui.control.Label
        DLabel                matlab.ui.control.Label
        CLabel                matlab.ui.control.Label
        ALabel                matlab.ui.control.Label
        LoadDButton           matlab.ui.control.Button
        LoadCButton           matlab.ui.control.Button
        LoadBButton           matlab.ui.control.Button
        SaveaspatternCButton  matlab.ui.control.Button
        SaveaspatternDButton  matlab.ui.control.Button
        SaveaspatternBButton  matlab.ui.control.Button
        LoadAButton           matlab.ui.control.Button
        SaveaspatternAButton  matlab.ui.control.Button
        ResetBPMButton        matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        BassdrumLabel         matlab.ui.control.Label
        SnaredrumLabel        matlab.ui.control.Label
        HighhatLabel          matlab.ui.control.Label
        ClapLabel             matlab.ui.control.Label
        ResetButton           matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        Switch_64             matlab.ui.control.ToggleSwitch
        Switch_63             matlab.ui.control.ToggleSwitch
        Switch_62             matlab.ui.control.ToggleSwitch
        Switch_61             matlab.ui.control.ToggleSwitch
        Switch_60             matlab.ui.control.ToggleSwitch
        Switch_59             matlab.ui.control.ToggleSwitch
        Switch_58             matlab.ui.control.ToggleSwitch
        Switch_57             matlab.ui.control.ToggleSwitch
        Switch_56             matlab.ui.control.ToggleSwitch
        Switch_55             matlab.ui.control.ToggleSwitch
        Switch_54             matlab.ui.control.ToggleSwitch
        Switch_53             matlab.ui.control.ToggleSwitch
        Switch_52             matlab.ui.control.ToggleSwitch
        Switch_51             matlab.ui.control.ToggleSwitch
        Switch_50             matlab.ui.control.ToggleSwitch
        Switch_49             matlab.ui.control.ToggleSwitch
        Switch_48             matlab.ui.control.ToggleSwitch
        Switch_47             matlab.ui.control.ToggleSwitch
        Switch_46             matlab.ui.control.ToggleSwitch
        Switch_45             matlab.ui.control.ToggleSwitch
        Switch_44             matlab.ui.control.ToggleSwitch
        Switch_43             matlab.ui.control.ToggleSwitch
        Switch_42             matlab.ui.control.ToggleSwitch
        Switch_41             matlab.ui.control.ToggleSwitch
        Switch_40             matlab.ui.control.ToggleSwitch
        Switch_39             matlab.ui.control.ToggleSwitch
        Switch_38             matlab.ui.control.ToggleSwitch
        Switch_37             matlab.ui.control.ToggleSwitch
        Switch_36             matlab.ui.control.ToggleSwitch
        Switch_35             matlab.ui.control.ToggleSwitch
        Switch_34             matlab.ui.control.ToggleSwitch
        Switch_33             matlab.ui.control.ToggleSwitch
        Switch_32             matlab.ui.control.ToggleSwitch
        Switch_31             matlab.ui.control.ToggleSwitch
        Switch_30             matlab.ui.control.ToggleSwitch
        Switch_29             matlab.ui.control.ToggleSwitch
        Switch_28             matlab.ui.control.ToggleSwitch
        Switch_27             matlab.ui.control.ToggleSwitch
        Switch_26             matlab.ui.control.ToggleSwitch
        Switch_25             matlab.ui.control.ToggleSwitch
        Switch_24             matlab.ui.control.ToggleSwitch
        Switch_23             matlab.ui.control.ToggleSwitch
        Switch_22             matlab.ui.control.ToggleSwitch
        Switch_21             matlab.ui.control.ToggleSwitch
        Switch_20             matlab.ui.control.ToggleSwitch
        Switch_19             matlab.ui.control.ToggleSwitch
        Switch_18             matlab.ui.control.ToggleSwitch
        Switch_17             matlab.ui.control.ToggleSwitch
        Switch_16             matlab.ui.control.ToggleSwitch
        Switch_15             matlab.ui.control.ToggleSwitch
        Switch_14             matlab.ui.control.ToggleSwitch
        Switch_13             matlab.ui.control.ToggleSwitch
        Switch_12             matlab.ui.control.ToggleSwitch
        Switch_11             matlab.ui.control.ToggleSwitch
        Switch_10             matlab.ui.control.ToggleSwitch
        Switch_9              matlab.ui.control.ToggleSwitch
        Switch_8              matlab.ui.control.ToggleSwitch
        Switch_7              matlab.ui.control.ToggleSwitch
        Switch_6              matlab.ui.control.ToggleSwitch
        Switch_5              matlab.ui.control.ToggleSwitch
        BPMKnob               matlab.ui.control.Knob
        BPMKnobLabel          matlab.ui.control.Label
        Switch_4              matlab.ui.control.ToggleSwitch
        Switch_3              matlab.ui.control.ToggleSwitch
        Switch_2              matlab.ui.control.ToggleSwitch
        Slider                matlab.ui.control.Slider
        Switch_1              matlab.ui.control.ToggleSwitch
        Image                 matlab.ui.control.Image
        Image_15              matlab.ui.control.Image
        Image_14              matlab.ui.control.Image
        Image_2               matlab.ui.control.Image
        Image_3               matlab.ui.control.Image
        Image_4               matlab.ui.control.Image
        Image_5               matlab.ui.control.Image
        Image_13              matlab.ui.control.Image
        Image_12              matlab.ui.control.Image
        Image_11              matlab.ui.control.Image
        Image_10              matlab.ui.control.Image
        Image_9               matlab.ui.control.Image
        Image_8               matlab.ui.control.Image
        Image_7               matlab.ui.control.Image
        Image_6               matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                 for i = 1:64
                 SwitchName = sprintf('Switch_%d', i);
                 app.(SwitchName).Value = 'off';
                 end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Lamp.Color ='green';
            for j = 1:1:30
            k=1;
            %for i = 1:1:16
            %app.Slider.Value = i;
            for i = 1:1:16
            BPM = app.BPMKnob.Value;
            num1 = (60/BPM)/4;
            app.Slider.Value = i;
            if app.Slider.Value == 1 && strcmp(app.Switch_1.Value, 'on')
            Clap();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_2.Value, 'on')
            HH();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_3.Value, 'on')
            Snare();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_4.Value, 'on')
            Bass();
            end
            if app.Slider.Value == 1
            pause(num1);
            continue;
            end
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Clap();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            HH();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Snare();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Bass();
            end
            k = k+1;
            pause(num1);
            if isequal(app.Lamp_2.Color, [1,0,0])
            app.Lamp_2.Color ='white';
            return;
            end
            end
            end
        end

        % Button pushed function: ResetBPMButton
        function ResetBPMButtonPushed(app, event)
            app.BPMKnob.Value = 100;
        end

        % Callback function
        function ResetButton_3Pushed(app, event)
            app.AccelerationKnob.Value = 0;
        end

        % Button pushed function: SaveaspatternAButton
        function SaveaspatternAButtonPushed(app, event)
            %MIDIファイル作成
            %addpath("C:\Users\westL\Dropbox\卒論\week10\midi");
            N = 16;                              % ノートの数
            MA = zeros(N * 4, 6);                 % N*4行6列の要素を0で初期化 (4つのトラック)
            BPM = app.BPMKnob.Value;
            bpm = (60/BPM)/4;

            % トラック1
            MA(1:N, 1) = 1;                       % トラック1にノート
            MA(1:N, 2) = 10;                       % チャンネル10にノート
            MA(1:N, 3) = 39;                      % トラック1のノートナンバー
            MA(1:N, 4) = 80;                      % 音量
            MA(1:N, 5) = (0:bpm:(N-1)*bpm)';      % ノートオン
            MA(1:N, 6) = MA(1:N, 5) + bpm;         % ノートオフ

            % トラック2
            MA(N+1:2*N, 1) = 2;                   % トラック2にノート
            MA(N+1:2*N, 2) = 10;                   % チャンネル10にノート
            MA(N+1:2*N, 3) = 42;                  % トラック2のノートナンバー
            MA(N+1:2*N, 4) = 80;                  % 音量
            MA(N+1:2*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MA(N+1:2*N, 6) = MA(N+1:2*N, 5) + bpm; % ノートオフ

            % トラック3
            MA(2*N+1:3*N, 1) = 3;                   % トラック3にノート
            MA(2*N+1:3*N, 2) = 10;                   % チャンネル10にノート
            MA(2*N+1:3*N, 3) = 38;                  % トラック3のノートナンバー
            MA(2*N+1:3*N, 4) = 80;                  % 音量
            MA(2*N+1:3*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MA(2*N+1:3*N, 6) = MA(2*N+1:3*N, 5) + bpm; % ノートオフ

            % トラック4
            MA(3*N+1:4*N, 1) = 4;                   % トラック4にノート
            MA(3*N+1:4*N, 2) = 10;                   % チャンネル10にノート
            MA(3*N+1:4*N, 3) = 35;                  % トラック4のノートナンバー
            MA(3*N+1:4*N, 4) = 80;                  % 音量
            MA(3*N+1:4*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MA(3*N+1:4*N, 6) = MA(3*N+1:4*N, 5) + bpm; % ノートオフ

            %ここでパターンを再現
            o1 = 1;
            k1 = 1;
            m1 = 1;
            n1 = 1;
            for a1 = 1:4:61
            if strcmp(app.(sprintf('Switch_%d', a1)).Value,'off') %1 5 9 13 17 21 25 29 33 37 41 45 49 53 57 61
            MA(o1,3) = 0;%1-16
            MA(o1,4) = 0;
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if strcmp(app.(sprintf('Switch_%d', a2)).Value,'off') %2 6 10 14 18 22 26 30 34 38 42 46 50 54 58 62
            MA((16+k1),3) = 0;%17-32
            MA((16+k1),4) = 0;
            end
            k1 = k1 + 1;
            end
            for a3 = 3:4:63
            if strcmp(app.(sprintf('Switch_%d', a3)).Value,'off') %3 7 11 15 19 23 27 31 35 39 43 47 51 55 59 63
            MA((32+m1),3) = 0;%33-48
            MA((32+m1),4) = 0;
            end
            m1 = m1 + 1;
            end
            for a4 = 4:4:64
            if strcmp(app.(sprintf('Switch_%d', a4)).Value,'off') %4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64
            MA((48+n1),3) = 0;%49-64
            MA((48+n1),4) = 0;
            end
            n1 = n1 + 1;
            end
            save('MA.mat','MA');
            % MIDIファイルを作成する
            midi = matrix2midi(MA);
            writemidi(midi, 'patternA.mid');

        end

        % Button pushed function: LoadAButton
        function LoadAButtonPushed(app, event)
           %Mを読み込んでトグルを上げ下げ
            o1 = 1;
            load('MA.mat');
            for a1 = 1:4:61
            if MA(o1,3) ~= 0
            app.(sprintf('Switch_%d', a1)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if MA(o1,3) ~= 0
            app.(sprintf('Switch_%d', a2)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a3 = 3:4:63
            if MA(o1,3) ~= 0
            app.(sprintf('Switch_%d', a3)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a4 = 4:4:64
            if MA(o1,3) ~= 0
            app.(sprintf('Switch_%d', a4)).Value = 'on';
            end
            o1 = o1 + 1;
            end
        end

        % Button pushed function: SaveaspatternBButton
        function SaveaspatternBButtonPushed(app, event)
            %MIDIファイル作成
            %addpath("C:\Users\westL\Dropbox\卒論\week10\midi");
            N = 16;                              % ノートの数
            MB = zeros(N * 4, 6);                 % N*4行6列の要素を0で初期化 (4つのトラック)
            BPM = app.BPMKnob.Value;
            bpm = (60/BPM)/4;

            % トラック1
            MB(1:N, 1) = 1;                       % トラック1にノート
            MB(1:N, 2) = 10;                       % チャンネル10にノート
            MB(1:N, 3) = 39;                      % トラック1のノートナンバー
            MB(1:N, 4) = 80;                      % 音量
            MB(1:N, 5) = (0:bpm:(N-1)*bpm)';      % ノートオン
            MB(1:N, 6) = MB(1:N, 5) + bpm;         % ノートオフ

            % トラック2
            MB(N+1:2*N, 1) = 2;                   % トラック2にノート
            MB(N+1:2*N, 2) = 10;                   % チャンネル10にノート
            MB(N+1:2*N, 3) = 42;                  % トラック2のノートナンバー
            MB(N+1:2*N, 4) = 80;                  % 音量
            MB(N+1:2*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MB(N+1:2*N, 6) = MB(N+1:2*N, 5) + bpm; % ノートオフ

            % トラック3
            MB(2*N+1:3*N, 1) = 3;                   % トラック3にノート
            MB(2*N+1:3*N, 2) = 10;                   % チャンネル10にノート
            MB(2*N+1:3*N, 3) = 38;                  % トラック3のノートナンバー
            MB(2*N+1:3*N, 4) = 80;                  % 音量
            MB(2*N+1:3*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MB(2*N+1:3*N, 6) = MB(2*N+1:3*N, 5) + bpm; % ノートオフ

            % トラック4
            MB(3*N+1:4*N, 1) = 4;                   % トラック4にノート
            MB(3*N+1:4*N, 2) = 10;                   % チャンネル10にノート
            MB(3*N+1:4*N, 3) = 35;                  % トラック4のノートナンバー
            MB(3*N+1:4*N, 4) = 80;                  % 音量
            MB(3*N+1:4*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MB(3*N+1:4*N, 6) = MB(3*N+1:4*N, 5) + bpm; % ノートオフ

            %ここでパターンを再現
            o1 = 1;
            k1 = 1;
            m1 = 1;
            n1 = 1;
            for a1 = 1:4:61
            if strcmp(app.(sprintf('Switch_%d', a1)).Value,'off') %1 5 9 13 17 21 25 29 33 37 41 45 49 53 57 61
            MB(o1,3) = 0;%1-16
            MB(o1,4) = 0;
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if strcmp(app.(sprintf('Switch_%d', a2)).Value,'off') %2 6 10 14 18 22 26 30 34 38 42 46 50 54 58 62
            MB((16+k1),3) = 0;%17-32
            MB((16+k1),4) = 0;
            end
            k1 = k1 + 1;
            end
            for a3 = 3:4:63
            if strcmp(app.(sprintf('Switch_%d', a3)).Value,'off') %3 7 11 15 19 23 27 31 35 39 43 47 51 55 59 63
            MB((32+m1),3) = 0;%33-48
            MB((32+m1),4) = 0;
            end
            m1 = m1 + 1;
            end
            for a4 = 4:4:64
            if strcmp(app.(sprintf('Switch_%d', a4)).Value,'off') %4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64
            MB((48+n1),3) = 0;%49-64
            MB((48+n1),4) = 0;
            end
            n1 = n1 + 1;
            end
            save('MB.mat','MB');
            % MIDIファイルを作成する
            midi = matrix2midi(MB);
            writemidi(midi, 'patternB.mid');
        end

        % Button pushed function: LoadBButton
        function LoadBButtonPushed(app, event)
            %Mを読み込んでトグルを上げ下げ
            o1 = 1;
            load('MB.mat');
            for a1 = 1:4:61
            if MB(o1,3) ~= 0
            app.(sprintf('Switch_%d', a1)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if MB(o1,3) ~= 0
            app.(sprintf('Switch_%d', a2)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a3 = 3:4:63
            if MB(o1,3) ~= 0
            app.(sprintf('Switch_%d', a3)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a4 = 4:4:64
            if MB(o1,3) ~= 0
            app.(sprintf('Switch_%d', a4)).Value = 'on';
            end
            o1 = o1 + 1;
            end
        end

        % Button pushed function: SaveaspatternCButton
        function SaveaspatternCButtonPushed(app, event)
            %MIDIファイル作成
            %addpath("C:\Users\westL\Dropbox\卒論\week10\midi");
            N = 16;                              % ノートの数
            MC = zeros(N * 4, 6);                 % N*4行6列の要素を0で初期化 (4つのトラック)
            BPM = app.BPMKnob.Value;
            bpm = (60/BPM)/4;

            % トラック1
            MC(1:N, 1) = 1;                       % トラック1にノート
            MC(1:N, 2) = 10;                       % チャンネル10にノート
            MC(1:N, 3) = 39;                      % トラック1のノートナンバー
            MC(1:N, 4) = 80;                      % 音量
            MC(1:N, 5) = (0:bpm:(N-1)*bpm)';      % ノートオン
            MC(1:N, 6) = MC(1:N, 5) + bpm;         % ノートオフ

            % トラック2
            MC(N+1:2*N, 1) = 2;                   % トラック2にノート
            MC(N+1:2*N, 2) = 10;                   % チャンネル10にノート
            MC(N+1:2*N, 3) = 42;                  % トラック2のノートナンバー
            MC(N+1:2*N, 4) = 80;                  % 音量
            MC(N+1:2*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MC(N+1:2*N, 6) = MC(N+1:2*N, 5) + bpm; % ノートオフ

            % トラック3
            MC(2*N+1:3*N, 1) = 3;                   % トラック3にノート
            MC(2*N+1:3*N, 2) = 10;                   % チャンネル10にノート
            MC(2*N+1:3*N, 3) = 38;                  % トラック3のノートナンバー
            MC(2*N+1:3*N, 4) = 80;                  % 音量
            MC(2*N+1:3*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MC(2*N+1:3*N, 6) = MC(2*N+1:3*N, 5) + bpm; % ノートオフ

            % トラック4
            MC(3*N+1:4*N, 1) = 4;                   % トラック4にノート
            MC(3*N+1:4*N, 2) = 10;                   % チャンネル10にノート
            MC(3*N+1:4*N, 3) = 35;                  % トラック4のノートナンバー
            MC(3*N+1:4*N, 4) = 80;                  % 音量
            MC(3*N+1:4*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MC(3*N+1:4*N, 6) = MC(3*N+1:4*N, 5) + bpm; % ノートオフ

            %ここでパターンを再現
            o1 = 1;
            k1 = 1;
            m1 = 1;
            n1 = 1;
            for a1 = 1:4:61
            if strcmp(app.(sprintf('Switch_%d', a1)).Value,'off') %1 5 9 13 17 21 25 29 33 37 41 45 49 53 57 61
            MC(o1,3) = 0;%1-16
            MC(o1,4) = 0;
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if strcmp(app.(sprintf('Switch_%d', a2)).Value,'off') %2 6 10 14 18 22 26 30 34 38 42 46 50 54 58 62
            MC((16+k1),3) = 0;%17-32
            MC((16+k1),4) = 0;
            end
            k1 = k1 + 1;
            end
            for a3 = 3:4:63
            if strcmp(app.(sprintf('Switch_%d', a3)).Value,'off') %3 7 11 15 19 23 27 31 35 39 43 47 51 55 59 63
            MC((32+m1),3) = 0;%33-48
            MC((32+m1),4) = 0;
            end
            m1 = m1 + 1;
            end
            for a4 = 4:4:64
            if strcmp(app.(sprintf('Switch_%d', a4)).Value,'off') %4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64
            MC((48+n1),3) = 0;%49-64
            MC((48+n1),4) = 0;
            end
            n1 = n1 + 1;
            end
            save('MC.mat','MC');
            % MIDIファイルを作成する
            midi = matrix2midi(MC);
            writemidi(midi, 'patternC.mid');
        end

        % Button pushed function: LoadCButton
        function LoadCButtonPushed(app, event)
            %Mを読み込んでトグルを上げ下げ
            o1 = 1;
            load('MC.mat');
            for a1 = 1:4:61
            if MC(o1,3) ~= 0
            app.(sprintf('Switch_%d', a1)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if MC(o1,3) ~= 0
            app.(sprintf('Switch_%d', a2)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a3 = 3:4:63
            if MC(o1,3) ~= 0
            app.(sprintf('Switch_%d', a3)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a4 = 4:4:64
            if MC(o1,3) ~= 0
            app.(sprintf('Switch_%d', a4)).Value = 'on';
            end
            o1 = o1 + 1;
            end
        end

        % Button pushed function: SaveaspatternDButton
        function SaveaspatternDButtonPushed(app, event)
            %MIDIファイル作成
            %addpath("C:\Users\westL\Dropbox\卒論\week10\midi");
            N = 16;                              % ノートの数
            MD = zeros(N * 4, 6);                 % N*4行6列の要素を0で初期化 (4つのトラック)
            BPM = app.BPMKnob.Value;
            bpm = (60/BPM)/4;

            % トラック1
            MD(1:N, 1) = 1;                       % トラック1にノート
            MD(1:N, 2) = 10;                       % チャンネル10にノート
            MD(1:N, 3) = 39;                      % トラック1のノートナンバー
            MD(1:N, 4) = 80;                      % 音量
            MD(1:N, 5) = (0:bpm:(N-1)*bpm)';      % ノートオン
            MD(1:N, 6) = MD(1:N, 5) + bpm;         % ノートオフ

            % トラック2
            MD(N+1:2*N, 1) = 2;                   % トラック2にノート
            MD(N+1:2*N, 2) = 10;                   % チャンネル10にノート
            MD(N+1:2*N, 3) = 42;                  % トラック2のノートナンバー
            MD(N+1:2*N, 4) = 80;                  % 音量
            MD(N+1:2*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MD(N+1:2*N, 6) = MD(N+1:2*N, 5) + bpm; % ノートオフ

            % トラック3
            MD(2*N+1:3*N, 1) = 3;                   % トラック3にノート
            MD(2*N+1:3*N, 2) = 10;                   % チャンネル10にノート
            MD(2*N+1:3*N, 3) = 38;                  % トラック3のノートナンバー
            MD(2*N+1:3*N, 4) = 80;                  % 音量
            MD(2*N+1:3*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MD(2*N+1:3*N, 6) = MD(2*N+1:3*N, 5) + bpm; % ノートオフ

            % トラック4
            MD(3*N+1:4*N, 1) = 4;                   % トラック4にノート
            MD(3*N+1:4*N, 2) = 10;                   % チャンネル10にノート
            MD(3*N+1:4*N, 3) = 35;                  % トラック4のノートナンバー
            MD(3*N+1:4*N, 4) = 80;                  % 音量
            MD(3*N+1:4*N, 5) = (0:bpm:(N-1)*bpm)';  % ノートオン
            MD(3*N+1:4*N, 6) = MD(3*N+1:4*N, 5) + bpm; % ノートオフ

            %ここでパターンを再現
            o1 = 1;
            k1 = 1;
            m1 = 1;
            n1 = 1;
            for a1 = 1:4:61
            if strcmp(app.(sprintf('Switch_%d', a1)).Value,'off') %1 5 9 13 17 21 25 29 33 37 41 45 49 53 57 61
            MD(o1,3) = 0;%1-16
            MD(o1,4) = 0;
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if strcmp(app.(sprintf('Switch_%d', a2)).Value,'off') %2 6 10 14 18 22 26 30 34 38 42 46 50 54 58 62
            MD((16+k1),3) = 0;%17-32
            MD((16+k1),4) = 0;
            end
            k1 = k1 + 1;
            end
            for a3 = 3:4:63
            if strcmp(app.(sprintf('Switch_%d', a3)).Value,'off') %3 7 11 15 19 23 27 31 35 39 43 47 51 55 59 63
            MD((32+m1),3) = 0;%33-48
            MD((32+m1),4) = 0;
            end
            m1 = m1 + 1;
            end
            for a4 = 4:4:64
            if strcmp(app.(sprintf('Switch_%d', a4)).Value,'off') %4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64
            MD((48+n1),3) = 0;%49-64
            MD((48+n1),4) = 0;
            end
            n1 = n1 + 1;
            end
            save('MD.mat','MD');
            % MIDIファイルを作成する
            midi = matrix2midi(MD);
            writemidi(midi, 'patternD.mid');
        end

        % Button pushed function: LoadDButton
        function LoadDButtonPushed(app, event)
            %Mを読み込んでトグルを上げ下げ
            o1 = 1;
            load('MD.mat');
            for a1 = 1:4:61
            if MD(o1,3) ~= 0
            app.(sprintf('Switch_%d', a1)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a2 = 2:4:62
            if MD(o1,3) ~= 0
            app.(sprintf('Switch_%d', a2)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a3 = 3:4:63
            if MD(o1,3) ~= 0
            app.(sprintf('Switch_%d', a3)).Value = 'on';
            end
            o1 = o1 + 1;
            end
            for a4 = 4:4:64
            if MD(o1,3) ~= 0
            app.(sprintf('Switch_%d', a4)).Value = 'on';
            end
            o1 = o1 + 1;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
        app.Lamp_2.Color ='red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_12 = uiimage(app.UIFigure);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_13 = uiimage(app.UIFigure);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_14 = uiimage(app.UIFigure);
            app.Image_14.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_15 = uiimage(app.UIFigure);
            app.Image_15.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Switch_1 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_1.Items = {'off', 'on'};
            app.Switch_1.Tag = 'Switch1';
            app.Switch_1.Value = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 16];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.Slider.Tag = 'Slider';
            app.Slider.Value = 1;
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'off', 'on'};
            app.Switch_2.Tag = 'Switch2';
            app.Switch_2.Value = 'off';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.Items = {'off', 'on'};
            app.Switch_3.Tag = 'Switch3';
            app.Switch_3.Value = 'off';
            app.Switch_4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_4.Items = {'off', 'on'};
            app.Switch_4.Tag = 'Switch4';
            app.Switch_4.Value = 'off';
            app.BPMKnobLabel = uilabel(app.UIFigure);
            app.BPMKnobLabel.Text = 'BPM';
            app.BPMKnob = uiknob(app.UIFigure, 'continuous');
            app.BPMKnob.Limits = [20 100];
            app.BPMKnob.MajorTicks = [20 40 60 80 100];
            app.BPMKnob.Value = 20;
            app.Switch_5 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_5.Items = {'off', 'on'};
            app.Switch_5.Tag = 'Switch6';
            app.Switch_5.Value = 'off';
            app.Switch_6 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_6.Items = {'off', 'on'};
            app.Switch_6.Tag = 'Switch7';
            app.Switch_6.Value = 'off';
            app.Switch_7 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_7.Items = {'off', 'on'};
            app.Switch_7.Tag = 'Switch8';
            app.Switch_7.Value = 'off';
            app.Switch_8 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_8.Items = {'off', 'on'};
            app.Switch_8.Tag = 'Switch9';
            app.Switch_8.Value = 'off';
            app.Switch_9 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_9.Items = {'off', 'on'};
            app.Switch_9.Tag = 'Switch10';
            app.Switch_9.Value = 'off';
            app.Switch_10 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_10.Items = {'off', 'on'};
            app.Switch_10.Tag = 'Switch11';
            app.Switch_10.Value = 'off';
            app.Switch_11 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_11.Items = {'off', 'on'};
            app.Switch_11.Tag = 'Switch12';
            app.Switch_11.Value = 'off';
            app.Switch_12 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_12.Items = {'off', 'on'};
            app.Switch_12.Tag = 'Switch13';
            app.Switch_12.Value = 'off';
            app.Switch_13 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_13.Items = {'off', 'on'};
            app.Switch_13.Tag = 'Switch14';
            app.Switch_13.Value = 'off';
            app.Switch_14 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_14.Items = {'off', 'on'};
            app.Switch_14.Tag = 'Switch15';
            app.Switch_14.Value = 'off';
            app.Switch_15 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_15.Items = {'off', 'on'};
            app.Switch_15.Tag = 'Switch16';
            app.Switch_15.Value = 'off';
            app.Switch_16 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_16.Items = {'off', 'on'};
            app.Switch_16.Tag = 'Switch17';
            app.Switch_16.Value = 'off';
            app.Switch_17 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_17.Items = {'off', 'on'};
            app.Switch_17.Tag = 'Switch18';
            app.Switch_17.Value = 'off';
            app.Switch_18 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_18.Items = {'off', 'on'};
            app.Switch_18.Tag = 'Switch19';
            app.Switch_18.Value = 'off';
            app.Switch_19 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_19.Items = {'off', 'on'};
            app.Switch_19.Tag = 'Switch20';
            app.Switch_19.Value = 'off';
            app.Switch_20 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_20.Items = {'off', 'on'};
            app.Switch_20.Tag = 'Switch21';
            app.Switch_20.Value = 'off';
            app.Switch_21 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_21.Items = {'off', 'on'};
            app.Switch_21.Tag = 'Switch22';
            app.Switch_21.Value = 'off';
            app.Switch_22 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_22.Items = {'off', 'on'};
            app.Switch_22.Tag = 'Switch23';
            app.Switch_22.Value = 'off';
            app.Switch_23 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_23.Items = {'off', 'on'};
            app.Switch_23.Tag = 'Switch24';
            app.Switch_23.Value = 'off';
            app.Switch_24 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_24.Items = {'off', 'on'};
            app.Switch_24.Tag = 'Switch25';
            app.Switch_24.Value = 'off';
            app.Switch_25 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_25.Items = {'off', 'on'};
            app.Switch_25.Tag = 'Switch26';
            app.Switch_25.Value = 'off';
            app.Switch_26 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_26.Items = {'off', 'on'};
            app.Switch_26.Tag = 'Switch27';
            app.Switch_26.Value = 'off';
            app.Switch_27 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_27.Items = {'off', 'on'};
            app.Switch_27.Tag = 'Switch28';
            app.Switch_27.Value = 'off';
            app.Switch_28 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_28.Items = {'off', 'on'};
            app.Switch_28.Tag = 'Switch29';
            app.Switch_28.Value = 'off';
            app.Switch_29 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_29.Items = {'off', 'on'};
            app.Switch_29.Tag = 'Switch30';
            app.Switch_29.Value = 'off';
            app.Switch_30 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_30.Items = {'off', 'on'};
            app.Switch_30.Tag = 'Switch31';
            app.Switch_30.Value = 'off';
            app.Switch_31 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_31.Items = {'off', 'on'};
            app.Switch_31.Tag = 'Switch32';
            app.Switch_31.Value = 'off';
            app.Switch_32 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_32.Items = {'off', 'on'};
            app.Switch_32.Tag = 'Switch33';
            app.Switch_32.Value = 'off';
            app.Switch_33 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_33.Items = {'off', 'on'};
            app.Switch_33.Tag = 'Switch34';
            app.Switch_33.Value = 'off';
            app.Switch_34 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_34.Items = {'off', 'on'};
            app.Switch_34.Tag = 'Switch35';
            app.Switch_34.Value = 'off';
            app.Switch_35 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_35.Items = {'off', 'on'};
            app.Switch_35.Tag = 'Switch36';
            app.Switch_35.Value = 'off';
            app.Switch_36 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_36.Items = {'off', 'on'};
            app.Switch_36.Tag = 'Switch37';
            app.Switch_36.Value = 'off';
            app.Switch_37 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_37.Items = {'off', 'on'};
            app.Switch_37.Tag = 'Switch38';
            app.Switch_37.Value = 'off';
            app.Switch_38 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_38.Items = {'off', 'on'};
            app.Switch_38.Tag = 'Switch39';
            app.Switch_38.Value = 'off';
            app.Switch_39 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_39.Items = {'off', 'on'};
            app.Switch_39.Tag = 'Switch40';
            app.Switch_39.Value = 'off';
            app.Switch_40 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_40.Items = {'off', 'on'};
            app.Switch_40.Tag = 'Switch41';
            app.Switch_40.Value = 'off';
            app.Switch_41 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_41.Items = {'off', 'on'};
            app.Switch_41.Tag = 'Switch42';
            app.Switch_41.Value = 'off';
            app.Switch_42 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_42.Items = {'off', 'on'};
            app.Switch_42.Tag = 'Switch43';
            app.Switch_42.Value = 'off';
            app.Switch_43 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_43.Items = {'off', 'on'};
            app.Switch_43.Tag = 'Switch44';
            app.Switch_43.Value = 'off';
            app.Switch_44 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_44.Items = {'off', 'on'};
            app.Switch_44.Tag = 'Switch45';
            app.Switch_44.Value = 'off';
            app.Switch_45 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_45.Items = {'off', 'on'};
            app.Switch_45.Tag = 'Switch46';
            app.Switch_45.Value = 'off';
            app.Switch_46 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_46.Items = {'off', 'on'};
            app.Switch_46.Tag = 'Switch47';
            app.Switch_46.Value = 'off';
            app.Switch_47 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_47.Items = {'off', 'on'};
            app.Switch_47.Tag = 'Switch48';
            app.Switch_47.Value = 'off';
            app.Switch_48 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_48.Items = {'off', 'on'};
            app.Switch_48.Tag = 'Switch49';
            app.Switch_48.Value = 'off';
            app.Switch_49 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_49.Items = {'off', 'on'};
            app.Switch_49.Tag = 'Switch50';
            app.Switch_49.Value = 'off';
            app.Switch_50 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_50.Items = {'off', 'on'};
            app.Switch_50.Tag = 'Switch51';
            app.Switch_50.Value = 'off';
            app.Switch_51 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_51.Items = {'off', 'on'};
            app.Switch_51.Tag = 'Switch52';
            app.Switch_51.Value = 'off';
            app.Switch_52 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_52.Items = {'off', 'on'};
            app.Switch_52.Tag = 'Switch53';
            app.Switch_52.Value = 'off';
            app.Switch_53 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_53.Items = {'off', 'on'};
            app.Switch_53.Tag = 'Switch54';
            app.Switch_53.Value = 'off';
            app.Switch_54 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_54.Items = {'off', 'on'};
            app.Switch_54.Tag = 'Switch55';
            app.Switch_54.Value = 'off';
            app.Switch_55 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_55.Items = {'off', 'on'};
            app.Switch_55.Tag = 'Switch5956';
            app.Switch_55.Value = 'off';
            app.Switch_56 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_56.Items = {'off', 'on'};
            app.Switch_56.Tag = 'Switch57';
            app.Switch_56.Value = 'off';
            app.Switch_57 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_57.Items = {'off', 'on'};
            app.Switch_57.Tag = 'Switch58';
            app.Switch_57.Value = 'off';
            app.Switch_58 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_58.Items = {'off', 'on'};
            app.Switch_58.Tag = 'Switch59';
            app.Switch_58.Value = 'off';
            app.Switch_59 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_59.Items = {'off', 'on'};
            app.Switch_59.Tag = 'Switch60';
            app.Switch_59.Value = 'off';
            app.Switch_60 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_60.Items = {'off', 'on'};
            app.Switch_60.Tag = 'Switch61';
            app.Switch_60.Value = 'off';
            app.Switch_61 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_61.Items = {'off', 'on'};
            app.Switch_61.Tag = 'Switch62';
            app.Switch_61.Value = 'off';
            app.Switch_62 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_62.Items = {'off', 'on'};
            app.Switch_62.Tag = 'Switch63';
            app.Switch_62.Value = 'off';
            app.Switch_63 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_63.Items = {'off', 'on'};
            app.Switch_63.Tag = 'Switch64';
            app.Switch_63.Value = 'off';
            app.Switch_64 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_64.Items = {'off', 'on'};
            app.Switch_64.Value = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ClapLabel = uilabel(app.UIFigure);
            app.ClapLabel.Text = 'Clap';
            app.HighhatLabel = uilabel(app.UIFigure);
            app.HighhatLabel.Text = 'High-hat';
            app.SnaredrumLabel = uilabel(app.UIFigure);
            app.SnaredrumLabel.Text = 'Snare drum';
            app.BassdrumLabel = uilabel(app.UIFigure);
            app.BassdrumLabel.Text = 'Bass drum';
            app.Lamp = uilamp(app.UIFigure);
            app.ResetBPMButton = uibutton(app.UIFigure, 'push');
            app.ResetBPMButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBPMButtonPushed, true);
            app.ResetBPMButton.Text = 'Reset BPM';
            app.SaveaspatternAButton = uibutton(app.UIFigure, 'push');
            app.SaveaspatternAButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaspatternAButtonPushed, true);
            app.SaveaspatternAButton.Text = {'Save as'; 'pattern A'};
            app.LoadAButton = uibutton(app.UIFigure, 'push');
            app.LoadAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAButtonPushed, true);
            app.LoadAButton.Text = 'Load A';
            app.SaveaspatternBButton = uibutton(app.UIFigure, 'push');
            app.SaveaspatternBButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaspatternBButtonPushed, true);
            app.SaveaspatternBButton.Text = {'Save as'; 'pattern B'};
            app.SaveaspatternDButton = uibutton(app.UIFigure, 'push');
            app.SaveaspatternDButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaspatternDButtonPushed, true);
            app.SaveaspatternDButton.Text = {'Save as'; 'pattern D'};
            app.SaveaspatternCButton = uibutton(app.UIFigure, 'push');
            app.SaveaspatternCButton.ButtonPushedFcn = createCallbackFcn(app, @SaveaspatternCButtonPushed, true);
            app.SaveaspatternCButton.Text = {'Save as'; 'pattern C'};
            app.LoadBButton = uibutton(app.UIFigure, 'push');
            app.LoadBButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBButtonPushed, true);
            app.LoadBButton.Text = 'Load B';
            app.LoadCButton = uibutton(app.UIFigure, 'push');
            app.LoadCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCButtonPushed, true);
            app.LoadCButton.Text = 'Load C';
            app.LoadDButton = uibutton(app.UIFigure, 'push');
            app.LoadDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDButtonPushed, true);
            app.LoadDButton.Text = 'Load D';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.CLabel = uilabel(app.UIFigure);
            app.CLabel.Text = 'C';
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = 'D';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end